classdef appGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PowerSwitchLabel               matlab.ui.control.Label
        PowerSwitch                    matlab.ui.control.ToggleSwitch
        HighTemperatureLampLabel       matlab.ui.control.Label
        HighTemperatureLamp            matlab.ui.control.Lamp
        CoolingFanLampLabel            matlab.ui.control.Label
        CoolingFanLamp                 matlab.ui.control.Lamp
        RPMGaugeLabel                  matlab.ui.control.Label
        RPMGauge                       matlab.ui.control.Gauge
        MPHGaugeLabel                  matlab.ui.control.Label
        MPHGauge                       matlab.ui.control.Gauge
        BatteryLifeGaugeLabel          matlab.ui.control.Label
        BatteryLifeGauge               matlab.ui.control.LinearGauge
        TotalDistancesmiTextAreaLabel  matlab.ui.control.Label
        TotalDistancesmiTextArea       matlab.ui.control.TextArea
        SetRPMKnobLabel                matlab.ui.control.Label
        SetRPMKnob                     matlab.ui.control.Knob
        LowCoolantLampLabel            matlab.ui.control.Label
        LowCoolantLamp                 matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        isOn % is the device set to on/off
        isTempAlarm % temp alarm
        isCoolantAlarm % coolant alarm
        batteryLife 
        rpmActual 
        rpmTarget
        isCoolingFan
        totalDistance
        s % serial port
    end
    
    methods (Access = private)
        
        
        function serial_data_available(app, obj, event)
            app.HighTemperatureLamp.Color = 'red';
            A = sscanf(obj,"%d");
            app.TotalDistancesmiTextArea.Value = "hi";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            port = 3;
            portString = ['COM' num2str(port)];
            app.s = serial(portString);
            app.s.BytesAvailableFcnMode = 'byte';
            %app.s.Terminator = 'CR';  % new line
            app.s.BytesAvailableFcnCount = 12;

            app.s.BytesAvailableFcn = {@app.serial_data_available};
            if isvalid(app.s)
                app.CoolingFanLamp.Color = 'red';
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            delete(app.s);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PowerSwitchLabel = uilabel(app.UIFigure);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.UIFigure, 'toggle');
            app.HighTemperatureLampLabel = uilabel(app.UIFigure);
            app.HighTemperatureLampLabel.Text = 'High Temperature';
            app.HighTemperatureLamp = uilamp(app.UIFigure);
            app.CoolingFanLampLabel = uilabel(app.UIFigure);
            app.CoolingFanLampLabel.Text = 'Cooling Fan';
            app.CoolingFanLamp = uilamp(app.UIFigure);
            app.RPMGaugeLabel = uilabel(app.UIFigure);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.UIFigure, 'circular');
            app.RPMGauge.Limits = [0 9600];
            app.MPHGaugeLabel = uilabel(app.UIFigure);
            app.MPHGaugeLabel.Text = 'MPH';
            app.MPHGauge = uigauge(app.UIFigure, 'circular');
            app.MPHGauge.Limits = [0 25];
            app.BatteryLifeGaugeLabel = uilabel(app.UIFigure);
            app.BatteryLifeGaugeLabel.Text = 'Battery Life';
            app.BatteryLifeGauge = uigauge(app.UIFigure, 'linear');
            app.TotalDistancesmiTextAreaLabel = uilabel(app.UIFigure);
            app.TotalDistancesmiTextAreaLabel.Text = 'Total Distances (mi)';
            app.TotalDistancesmiTextArea = uitextarea(app.UIFigure);
            app.SetRPMKnobLabel = uilabel(app.UIFigure);
            app.SetRPMKnobLabel.Text = 'Set RPM';
            app.SetRPMKnob = uiknob(app.UIFigure, 'continuous');
            app.SetRPMKnob.Limits = [0 9600];
            app.LowCoolantLampLabel = uilabel(app.UIFigure);
            app.LowCoolantLampLabel.Text = 'Low Coolant';
            app.LowCoolantLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end